classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectDatasetButton           matlab.ui.control.Button
        VisualizationtypeButtonGroup  matlab.ui.container.ButtonGroup
        TimelapseButton               matlab.ui.control.RadioButton
        DifferenceImageButton         matlab.ui.control.RadioButton
        SliderButton                  matlab.ui.control.RadioButton
        ColordistributionButton       matlab.ui.control.RadioButton
        AnalyzeButton                 matlab.ui.control.Button
        ModeofChangeDropDownLabel     matlab.ui.control.Label
        ModeofChangeDropDown          matlab.ui.control.DropDown
        ImageAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        viz_type % which type of visualization to produce
    end
    properties (Access = private)
        mode_change % which mode of visualization chosen in Drop down
    end
   properties (Access = private)
        target_folder % which mode of visualization chosen in Drop down
    end
    
    methods (Access = private)
        
        function update_image(app,target_folder,imageFiles,i)
            
            %imageFiles=dir([target_folder '/*.jpg']);
            imageFileName = imageFiles(i).name;
            
            
            fullFileName = fullfile(target_folder, imageFileName);
            
            try
                im = imread(fullFileName);
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            imagesc(app.ImageAxes,im);
        end
        
        function welcome(app)
         message = {'Hi there!', ' ', 'This is just a quick info for you!', ' ',  'First, you have to browse a scene with the Browse button.', ' ', 'Second you can choose the visualization mode and type to your preference.', ' ', 'Now, you can start the app with the "Analyse" button.', ' ', 'With the LOOP button, you can choose to play the videos in an infinite loop.', ' ', 'ENJOY :)' };

         d = dialog('Position',[300 300 500 300],'Name','Welcome!');

         txt = uicontrol('Parent',d,...
               'Style','text',...
               'Position',[40 80 420 200],...
               'String',message);

         btn = uicontrol('Parent',d,...
               'Position',[220 20 70 25],...
               'String','Close',...
               'Callback','delete(gcf)');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImageAxes.Visible = 'on';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            % welcome dialog box
            welcome(app);
            % Initial values (Default)
            app.viz_type='Timelapse';
            app.mode_change='Quick';
            
            app.AnalyzeButton.BackgroundColor=[1.00,0.00,0.00];
            
        end

        % Callback function: ImageAxes, SelectDatasetButton
        function LoadDatasetsButtonPushed(app, event)
            
            % Display uigetfile dialog
            
            app.target_folder = uigetdir('Load a dataset ');
            
            % Make sure user didn't cancel uigetfile dialog
            if ~isfolder(app.target_folder)
                errorMessage = sprintf('Error: The target folder does not exist:\n%s', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            imageFiles=dir([app.target_folder '/*.jpg'])
            
            if size(imageFiles,1)==0
                errorMessage = sprintf('Error: The target folder does not contain any images. Please choose another!', app.target_folder);
                uiwait(warndlg(errorMessage));
                return;
            end
            
            app.AnalyzeButton.BackgroundColor=[0,1.00,0.00];


        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            imageFiles=dir([app.target_folder '/*.jpg']); % all images
            Nfile=size(imageFiles,1);                     % Number of images
            tfol= app.target_folder;                      % target folder
            
            if strcmp(app.viz_type,'Timelapse') 
                %create a timelapse visualization
                app.VisualizationtypeButtonGroup.BackgroundColor=[0.00,1.00,0.00];
                
                [image_Array,~]=timelapse(app.target_folder);
                for i=1:Nfile 
                    imagesc(app.ImageAxes,image_Array{i});
                    pause(1);   % pause for 1s between two images
                end
            end
            if strcmp(app.viz_type,'Difference Image')  
                %create difference image visualization
                app.VisualizationtypeButtonGroup.BackgroundColor=[1.00,0.00,0.00];
            end
            if strcmp(app.viz_type,'Slider')  
                image_left=imread(fullfile(app.target_folder,imageFiles(1).name));
                image_right=imread(fullfile(app.target_folder,imageFiles(end).name));
                [tform, ~]=register(rgb2gray(image_left),rgb2gray(image_right),0);
                image_right = imwarp(image_right,tform,'OutputView',imref2d(size(image_left)));
                
                slider(app.ImageAxes,image_left,image_right)
            end
            if strcmp(app.viz_type,'Color distribution?')  

            end


        end

        % Selection changed function: VisualizationtypeButtonGroup
        function VisualizationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VisualizationtypeButtonGroup.SelectedObject;
            app.viz_type=selectedButton.Text;
        end

        % Value changed function: ModeofChangeDropDown
        function ModeofChangeDropDownValueChanged(app, event)
            value = app.ModeofChangeDropDown.Value;
            app.mode_change = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectDatasetButton = uibutton(app.UIFigure, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.VisualizationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.VisualizationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualizationtypeButtonGroupSelectionChanged, true);
            app.VisualizationtypeButtonGroup.Title = 'Visualization type';
            app.TimelapseButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.TimelapseButton.Text = 'Timelapse';
            app.TimelapseButton.Value = true;
            app.DifferenceImageButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.DifferenceImageButton.Text = 'Difference Image';
            app.SliderButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.ColordistributionButton = uiradiobutton(app.VisualizationtypeButtonGroup);
            app.ColordistributionButton.Text = 'Color distribution?';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ModeofChangeDropDownLabel = uilabel(app.UIFigure);
            app.ModeofChangeDropDownLabel.Text = 'Mode of Change';
            app.ModeofChangeDropDown = uidropdown(app.UIFigure);
            app.ModeofChangeDropDown.Items = {'Quick', 'Slow', 'Little', 'Large'};
            app.ModeofChangeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeofChangeDropDownValueChanged, true);
            app.ModeofChangeDropDown.Value = 'Quick';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Output')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ButtonDownFcn = createCallbackFcn(app, @LoadDatasetsButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end